classdef ExerciseCEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figExerciseCEST         matlab.ui.Figure
        NormalizationGroup      matlab.ui.container.ButtonGroup
        radNeg                  matlab.ui.control.RadioButton
        radM0                   matlab.ui.control.RadioButton
        radMT                   matlab.ui.control.RadioButton
        LabelEditField          matlab.ui.control.Label
        txtMainDir              matlab.ui.control.EditField
        butChangeDir            matlab.ui.control.Button
        LabelNumericEditField   matlab.ui.control.Label
        numCESTppm              matlab.ui.control.NumericEditField
        B0TolLabel              matlab.ui.control.Label
        numB0thresh             matlab.ui.control.NumericEditField
        B1ThreshLabel           matlab.ui.control.Label
        numB1thresh             matlab.ui.control.NumericEditField
        butChooseROI            matlab.ui.control.Button
        butCalculate            matlab.ui.control.Button
        DisplayPanel            matlab.ui.container.Panel
        butB0map                matlab.ui.control.Button
        butCESTw_im             matlab.ui.control.Button
        butB1map                matlab.ui.control.Button
        butROI                  matlab.ui.control.Button
        butROIrecover           matlab.ui.control.Button
        butCESTmap              matlab.ui.control.Button
        preExPanel              matlab.ui.container.Panel
        Label2                  matlab.ui.control.Label
        txtB1Pre                matlab.ui.control.EditField
        Label3                  matlab.ui.control.Label
        txtCESTPre              matlab.ui.control.EditField
        Label8                  matlab.ui.control.Label
        txtB0Pre                matlab.ui.control.EditField
        Label9                  matlab.ui.control.Label
        txtRefPre               matlab.ui.control.EditField
        postExPanel             matlab.ui.container.Panel
        Label4                  matlab.ui.control.Label
        txtRefPost              matlab.ui.control.EditField
        Label5                  matlab.ui.control.Label
        txtB0Post               matlab.ui.control.EditField
        Label6                  matlab.ui.control.Label
        txtB1Post               matlab.ui.control.EditField
        Label7                  matlab.ui.control.Label
        txtCESTPost             matlab.ui.control.EditField
        LabelDropDown           matlab.ui.control.Label
        ChangeDirDropDown       matlab.ui.control.DropDown
        chkWassr                matlab.ui.control.CheckBox
        LabelEditField2         matlab.ui.control.Label
        txtDisp                 matlab.ui.control.EditField
        butSave                 matlab.ui.control.Button
        butLoad                 matlab.ui.control.Button
        LabelNumericEditField3  matlab.ui.control.Label
        numROIs                 matlab.ui.control.NumericEditField
        chkFilter               matlab.ui.control.CheckBox
        butExport               matlab.ui.control.Button
        butClear                matlab.ui.control.Button
        chkRegister             matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        %         mainDir % Main directory where dicoms are
        %         curDir % Current directory from which GUI is run
    end
    
    properties (Access = public)
        mainDir             % Main directory where dicoms are
        curDir              % Current directory from which GUI is run
        subdirs             % Subdirectories
        pre                 % Pre exercise stuff
        post                % Post exercise stuff
        handle              % handles for all figures
        fit                 % Fitting stuff
    end
    
    methods (Access = private)
        
        function setDisplays(app)
            if isfield(app.subdirs,'Refpre')
                app.txtRefPre.Value = app.subdirs.Refpre;
            end
            if ~app.chkWassr.Value && isfield(app.subdirs,'B0magpre')
                app.txtB0Pre.Value = app.subdirs.B0magpre;
            elseif app.chkWassr.Value && isfield(app.subdirs,'WASSRpre')
                app.txtB0Pre.Value = app.subdirs.WASSRpre;
            end
            if isfield(app.subdirs,'B1pre')
                app.txtB1Pre.Value = app.subdirs.B1pre;
            end
            if isfield(app.subdirs,'CESTpre')
                app.txtCESTPre.Value = app.subdirs.CESTpre;
            end
            if isfield(app.subdirs,'Refpost')
                app.txtRefPost.Value = app.subdirs.Refpost;
            end
            if ~app.chkWassr.Value && isfield(app.subdirs,'B0magpost')
                app.txtB0Post.Value = app.subdirs.B0magpost;
            elseif app.chkWassr.Value && isfield(app.subdirs,'WASSRpost')
                app.txtB0Post.Value = app.subdirs.WASSRpost;
            end
            if isfield(app.subdirs,'B1post')
                app.txtB1Post.Value = app.subdirs.B1post;
            end
            if isfield(app.subdirs,'CESTpost')
                app.txtCESTPost.Value = app.subdirs.CESTpost;
            end
        end
        
        function norm = getNormalization(app)
            if app.radNeg.Value==1        % Negative
                norm = 0;
            elseif app.radM0.Value==1     % M0
                norm = 1;
            else                          % MT
                norm = 2;
            end
        end
        
        function setNormalization(app,norm)
            if norm==0
                app.radNeg.Value = 1;
            elseif norm==1
                app.radM0.Value = 1;
            elseif norm==2
                app.radMT.Value = 1;
            else
                warndlg({'Invalid normalization value...';'Using negative normalization'});
                app.radNeg.Value = 1;
            end
        end
        
        function printROIstats(app)
            nrois = app.fit.nrois;
            npars = size(app.fit.pars,1);
            colors = 255*app.fit.colors;
            parnames = app.fit.parnames;
            if isfield(app.fit,'roinames')
                roinames = app.fit.roinames;
            else
                roinames = [];
            end
            
            for ii=1:nrois
                colstr = sprintf('rgb(%d,%d,%d)',colors(ii,1),colors(ii,2),colors(ii,3));
                if isempty(roinames)
                    cnames{ii} = coloredText('ROI',colstr);
                else
                    cnames{ii} = coloredText(roinames{ii},colstr);
                end
            end
            h = figure('Name','ROI Fit ','NumberTitle','off');
            h.Position(3:4) = [350,200];
            t = uitable(h,'Data',cat(1,app.fit.pars,app.fit.roipix.post,app.fit.roipix.pre),'ColumnName',cnames,'RowName',parnames);
            t.FontWeight = 'bold';
            t.Units = 'normalized';
            t.Position = [0 0 1 1];
            t.Units = 'pixel';
            h.Position(3) = t.Extent(3)*1.1;
            %             h.Position(2) = h.Position(2) + h.Position(4) - t.Extent(4);
            t.Units = 'normalized';
            t.Position = t.Extent;
            t.Position(2) = 1 - t.Position(4);
            
            button = questdlg('Save table?','','Yes','No','Yes');
            if strcmp(button,'Yes')
                saveas(h,fullfile(app.mainDir,'results.png'));
            end
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if license('test','image_toolbox')
                app.chkRegister.Enable = 'on';
            end
            button = questdlg('Keep Diary?','','Yes','No','No');
            if strcmp(button,'Yes')
                logfile = sprintf('ExerciseCEST%s.log',datestr(now,'yyyymmddHHMMSS'));
                diary(logfile);
            end
            addpath('common','-begin')
            if isunix && exist('/media/cmroi/2A3E6A7F79133A73','dir')
                app.mainDir = '/media/cmroi/2A3E6A7F79133A73'; % 140GB hard drive
            elseif ispc && exist('D:','dir')
                app.mainDir = 'D:'; % 140 GB hard drive
            else
                app.mainDir = pwd;
            end            
            app.txtMainDir.Value = app.mainDir;
            app.curDir = pwd;
            app.fit.colors = [1 0 0;
                0 1 0;
                0 0 1;
                1 0.5 0;
                1 1 0];
            
            % Includes linear fit for pre-exercise baseline
            app.fit.myfun = @(a,t) (t>0).*(a(1)*exp(-t/a(2))+a(3)) + (t<=0).*(a(4)*t+a(5)); % exponential decay post exercise, linear pre exercise
            app.fit.par0 = [6, 150, 15, 0, 6]; % 6 is baseline, 150 is time constant, 15 is amplitude, 0 is slope, 6 is baseline
            
            % Includes constant fit for pre-exercise baseline
            app.fit.myfun_con = @(a,t) (t>0).*(a(1)*exp(-t/a(2))+a(3)) + (t<=0).*(a(4)); % exponential decay post exercise, constant pre exercise
            app.fit.par0_con = [6, 150, 15, 6]; % 6 is baseline, 150 is time constant, 15 is amplitude, 6 is baseline
            
            pfiles = dir('params*');
            
            if length(pfiles)>1
                pfilename = uigetfile('params*','Choose Parameters file');
            elseif length(pfiles)==1
                pfilename = pfiles.name;
            else
                pfilename = 0;
            end
            
            if ~isequal(pfilename,0)
                fileid = fopen(pfilename,'r');
                P = textscan(fileid,'%s %q','Delimiter',',');
                
                for ii=1:length(P{1})
                    temp = lower(P{1}{ii});
                    if strcmp(temp,'myfun')
                        app.fit.myfun = eval(P{2}{ii});
                    elseif strcmp(temp,'par0')
                        app.fit.par0 = eval(P{2}{ii});
                    elseif strcmp(temp,'parnames')
                        app.fit.parnames = eval(P{2}{ii});
                        app.fit.parnames_flag = true;
                    elseif strcmp(temp,'maindir')
                        app.mainDir = P{2}{ii};
                    elseif strcmp(temp,'colors')
                        app.fit.colors = eval(P{2}{ii});
                    elseif strcmp(temp,'rois')
                        app.fit.roinames = eval(P{2}{ii});
                    end
                end
            end
            
            app.txtMainDir.Value = app.mainDir;
            
            
        end

        % Button pushed function: butChangeDir
        function ChangeDir(app, event)
            temp = uigetdir(app.mainDir);
            if temp
                tempspl = strsplit(temp,filesep);
                if strcmp(tempspl{2},'DICOM') % DVD drive requires sorting
                    temp = cdsort(temp);
                end
                app.mainDir = temp;
                app.txtMainDir.Value = app.mainDir;
                out = NWexerciseCESTsort(app.mainDir);
                if ~isempty(out)
                    app.subdirs = out;
                    setDisplays(app);
                else
                    msgbox('No matching folders found. Check your main directory.','Error')
                end
            end
        end

        % Button pushed function: butViewSubDir
        function ViewSubDirs(app, event)
            warning('this should not have happened')
        end

        % Value changed function: txtMainDir
        function txtMainDirValueChanged(app, event)
            app.mainDir = app.txtMainDir.Value;
        end

        % Value changed function: ChangeDirDropDown
        function ChangeDirDropDownValueChanged(app, event)
            value = app.ChangeDirDropDown.Value;
            if ~strcmp(value,'')
                new_dir  = uigetdir(app.mainDir);
                if new_dir
                    new_subdir = strsplit(new_dir,filesep);
                    new_subdir = new_subdir{end};
                    if strcmp(value,'Ref Pre')
                        app.subdirs.Refpre = new_subdir;
                    elseif strcmp(value,'B0 Pre')
                        app.subdirs.B0magpre = new_subdir;
                        if strcmpi(new_subdir(1),'s') && ~isnan(str2double(new_subdir(2:5)))
                            ind =  str2double(new_subdir(2:5));
                            app.subdirs.B0phpre = [new_subdir(1) num2str(ind+1,'%04d') new_subdir(6:end)];
                        else
                            splitname = strsplit(new_subdir,'_');
                            ind = str2double(splitname(end));
                            app.subdirs.B0phpre = [strjoin(splitname(1:end-1),'_') '_' num2str(ind+1,'%04d')];
                        end
                    elseif strcmp(value,'B1 Pre')
                        app.subdirs.B1pre = new_subdir;
                    elseif strcmp(value,'CEST Pre')
                        app.subdirs.CESTpre = new_subdir;
                    elseif strcmp(value,'Ref Post')
                        app.subdirs.Refpost = new_subdir;
                    elseif strcmp(value,'B0 Post')
                        app.subdirs.B0magpost = new_subdir;
                        if strcmpi(new_subdir(1),'s') && ~isnan(str2double(new_subdir(2:5)))
                            ind =  str2double(new_subdir(2:5));
                            app.subdirs.B0phpost = [new_subdir(1) num2str(ind+1,'%04d') new_subdir(6:end)];
                        else
                            splitname = strsplit(new_subdir);
                            ind = str2double(splitname(end));
                            app.subdirs.B0phpost = [strjoin(splitname(1:end),'_') '_' num2str(ind+1,'%04d')];
                        end
                    elseif strcmp(value,'B1 Post')
                        app.subdirs.B1post = new_subdir;
                    elseif strcmp(value,'CEST Post')
                        app.subdirs.CESTpost = new_subdir;
                    end
                    setDisplays(app);
                end
                app.ChangeDirDropDown.Value = '';
            end
        end

        % Button pushed function: butCalculate
        function butCalculateButtonPushed(app, event)
            try
                
                app.fit.isfilt = app.chkFilter.Value;
                
                %% Reference
                app.txtDisp.Value = 'Loading reference images';
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.Refpre);
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.Refpost);
                    end
                    [hdr,refimage,dicomhdr] = readdicomfiles2d(pathname1);
                    %                     disp([ii 'Ref center frequency: ' num2str(hdr.sf)])
                    
                    if strcmp(ii,'pre')
                        app.pre.refdicomhdr = dicomhdr;
                        app.pre.refhdr = hdr;
                        app.pre.refimage = refimage;
                        app.pre.CF = hdr.sf;
                        app.pre.roimask = ones(size(refimage)); % temporary
                    else
                        app.post.refdicomhdr = dicomhdr;
                        app.post.refhdr = hdr;
                        app.post.refimage = refimage;
                        app.post.CF = hdr.sf;
                        app.post.roimask = ones(size(refimage)); % temporary
                    end
                    
                    [H,W,Z] = size(refimage);
                    if Z>1
                        refimage = squeeze(refimage(:,:,1));
                        warndlg(' Multiple Reference images found...using the first.');
                    end
                    if app.fit.isfilt
                        refimage = anisodiff(refimage,10,50,0.03,1);
                    end
                    refimage = 2000.0 * refimage/max(refimage(:));
                    noise = refimage(refimage < 100); % was 50
                    noisestd = std(noise(:));
                    threshold = 8 * noisestd; % was 4
                    biasimages2 = anisodiff(refimage,10,10000,0.25,1);
                    mask = zeros(H,W);
                    im1 = refimage;
                    maxval1 = max(im1(:));
                    mask(im1 > threshold) = 1.0;
                    im = biasimages2;
                    maxval = max(im(:));
                    im = maxval ./ (im+0.01);
                    im (mask == 0 ) = 1.0;
                    im (im < 0) = 1.0;
                    im (im > 30) = 1.0; % was 50
                    im1 =  (refimage .* mask) .* im;
                    im(im1 > maxval1) = im(im1 > maxval1) .* (maxval1 ./ (im1(im1 > maxval1)));  % Division to obtain biasscale
                    biasscale = im;
                    refimageb = refimage .* biasscale;
                    refimageb = 2000.0 * refimageb/max(refimageb(:));
                    
                    if strcmp(ii,'pre')
                        app.pre.bias = biasscale;
                        app.pre.refimageb = refimageb;
                        app.pre.roimask = mask;
                    else
                        app.post.bias = biasscale;
                        app.post.refimageb = refimageb;
                        app.post.roimask = mask;
                    end
                end
                
                %% Get registration transform
                if app.chkRegister.Value
                    app.txtDisp.Value = 'Registering reference images';
                    refpre = app.pre.refimageb.*app.pre.roimask;
                    refpost = app.post.refimageb.*app.post.roimask;
                    tform1 = imregcorr(refpre,refpost,'rigid');
                    
                    [optimizer,metric] = imregconfig('monomodal');
                    tform = imregtform(refpre,refpost,'affine',optimizer,metric,'InitialTransformation',tform1); % uses affine transform between pre/post exercise
                    
                    app.pre.tform = tform;
                    
                    % Apply transformation to ALL pre-exercise scans
                    app.txtDisp.Value = 'Applying tranformations';
                    app.pre.refimage = imwarp(app.pre.refimage,tform,'OutputView',imref2d(size(app.post.refimage)));
                    app.pre.refimageb = imwarp(app.pre.refimageb,tform,'OutputView',imref2d(size(app.post.refimageb)));
                    app.pre.roimask = app.post.roimask;
                end
                    
                
                %% B0
                if app.chkWassr.Value
                    app.txtDisp.Value = 'Loading WASSR images';
                    for ii={'pre','post'}
                        if strcmp(ii,'pre')
                            pathname1 = fullfile(app.mainDir,app.subdirs.WASSRpre);
                            roimask = app.pre.roimask;
                        else
                            pathname1 = fullfile(app.mainDir,app.subdirs.WASSRpost);
                            roimask = app.post.roimask;
                        end
                        [hdr,images,dicomhdr] = readdicomfiles2d(pathname1);
                        %                         disp([ii 'WASSR center frequency: ' num2str(hdr.sf)])
                        
                        if app.chkRegister.Value && strcmp(ii,'pre')
                            app.txtDisp.Value = 'Applying tranformations';
                            for jj=1:size(images,3)
                                images(:,:,jj) = imwarp(images(:,:,jj),app.pre.tform,'OutputView',imref2d(size(app.post.refimage)));
                            end
                        end
                        
                        nfreq = hdr.reps/2;
                        nfiles = hdr.reps;
                        hdd = hdr;
                        
                        seqname = hdr.SeqName;
                        swversion = hdr.swversion;
                        if ( strfind( swversion, 'VD13A' ) )
                            wipppmindex = 3;
                            prepmodeindex = 1;
                            cestz2value = 5;
                            WASSRpw = hdr.WIPlong(11);
                            WASSRpw1 = hdr.WIPlong(13);
                            WASSRb1 = hdr.WIPlong(14);
                            WASSRdc = hdr.WIPdbl(1);
                        elseif ( strfind( swversion, 'VD13D' ) )                            
                            seqname = strsplit(seqname, '\');
                            seqname = seqname{end};
                            
                            if strcmp(seqname,'prep_moco')
                                wipppmindex = 2;
                                prepmodeindex = 2;
                                cestz2value = 3;
                                WASSRpw = 0.001*hdr.WIPlong(13);
                                WASSRpw1 = 0.001*hdr.WIPlong(14);
                                WASSRb1 = hdr.WIPlong(16);
                                WASSRdc = hdr.WIPdbl(1);
                            elseif strcmp(seqname,'prep_moco_iB0')
                                wipppmindex = 3; % iB0
                                prepmodeindex = 2;
                                cestz2value = 3;
                                WASSRpw = 0.001*hdr.WIPlong(15); % iB0
                                WASSRpw1 = 0.001*hdr.WIPlong(16); % iB0
                                WASSRb1 = hdr.WIPlong(18); % iB0
                                WASSRdc = hdr.WIPdbl(1);
                            else
                                wipppmindex = input(' Type in the value for wipppmindex : ');
                                prepmodeindex = input(' Type in the value for prepmodeindex : ');
                                WASSRb1 = input(' Type in the value for CEST b1 in Hz : ');
                                WASSRpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                WASSRpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                WASSRdc = input(' Type in the value for CEST dutycycle in % : ');
                            end
                        else
                            if (strfind(seqname,'prep_moco'))
                                wipppmindex = 2;
                                prepmodeindex = 2;
                                cestz2value = 3;
                                WASSRpw = 0.001*hdr.WIPlong(13);
                                WASSRpw1 = 0.001*hdr.WIPlong(14);
                                WASSRb1 = hdr.WIPlong(16);
                                WASSRdc = hdr.WIPdbl(1);
                            elseif (strfind(seqname,'prep_cv'))
                                wipppmindex = 2;
                                prepmodeindex = 1;
                                cestz2value = 8;
                                if ( hdr.WIPlong(19) >= 170 )
                                    WASSRpw = hdr.WIPlong(10);
                                    WASSRb1 = hdr.WIPlong(13);
                                    WASSRpw1 = hdr.WIPlong(11);
                                    WASSRdc = hdr.WIPdbl(1);
                                else
                                    WASSRb1 = input(' Type in the value for CEST b1 in Hz : ');
                                    WASSRpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                    WASSRpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                    WASSRdc = input(' Type in the value for CEST dutycycle in % : ');
                                end
                            elseif (strfind(seqname,'prep_flash'))
                                prepmodeindex = 1;
                                cestz2value = 8;
                                if ( hdr.WIPlong(19) >= 170 )
                                    WASSRpw = hdr.WIPlong(11);
                                    WASSRb1 = hdr.WIPlong(12);
                                    WASSRpw1 = hdr.WIPlong(15);
                                    WASSRdc = hdr.WIPdbl(1);
                                    wipppmindex = 3;
                                else
                                    WASSRb1 = input(' Type in the value for CEST b1 in Hz : ');
                                    WASSRpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                    WASSRpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                    WASSRdc = input(' Type in the value for CEST dutycycle in % : ');
                                    wipppmindex = 5;
                                end
                            end                    
                        end
                        app.txtDisp.Value = 'Calculating WASSR B0 map';
                        posimages = images(:,:,1:2:end);
                        negimages = images(:,:,2:2:end);
                        ppmbegin = hdr.WIPdbl(wipppmindex+1);  % begin
                        ppmend   = hdr.WIPdbl(wipppmindex+2);  % end
                        ppmstep  = hdr.WIPdbl(wipppmindex+3);  % step
                        if (ppmbegin > ppmend)
                            ppmstep = -ppmstep;
                        end
                        WASSRppmlist = ppmbegin:ppmstep:ppmend;
                        B0map = mexwassr_b0map2d(WASSRppmlist, posimages, negimages, double(roimask)); % crashes without appropriate roimask
                        
                        if 1 % Always filter B0map app.fit.isfilt
                            B0map = anisodiff(B0map,20,50,0.03,1);
                        end
                        
                        roimask( abs(B0map) > app.numB0thresh.Value ) = 0;
                        if strcmp(ii,'pre')
                            app.pre.B0map = B0map;
                            app.pre.WASSRppmlist = WASSRppmlist;
                            app.pre.nWASSRppm = length( WASSRppmlist);
                            app.pre.WASSRposimages = posimages;
                            app.pre.WASSRnegimages = negimages;
                            app.pre.WASSRhdr = hdr;
                            app.pre.WASSRb1 = WASSRb1;
                            app.pre.WASSRpw = WASSRpw;
                            app.pre.WASSRpw1 = WASSRpw1;
                            app.pre.WASSRdc = WASSRdc;
                            app.pre.roimask = roimask;
                        else
                            app.post.B0map = B0map;
                            app.post.WASSRppmlist = WASSRppmlist;
                            app.post.nWASSRppm = length( WASSRppmlist);
                            app.post.WASSRposimages = posimages;
                            app.post.WASSRnegimages = negimages;
                            app.post.WASSRhdr = hdr;
                            app.post.WASSRb1 = WASSRb1;
                            app.post.WASSRpw = WASSRpw;
                            app.post.WASSRpw1 = WASSRpw1;
                            app.post.WASSRdc = WASSRdc;
                            app.post.roimask = roimask;
                        end
                    end
                else
                    for ii={'pre','post'}
                        app.txtDisp.Value = 'Loading B0 GRE images';
                        if strcmp(ii,'pre')
                            pathname1 = fullfile(app.mainDir,app.subdirs.B0magpre);
                            pathname2 = fullfile(app.mainDir,app.subdirs.B0phpre);
                            roimask = app.pre.roimask;
                        else
                            pathname1 = fullfile(app.mainDir,app.subdirs.B0magpost);
                            pathname2 = fullfile(app.mainDir,app.subdirs.B0phpost);
                            roimask = app.post.roimask;
                        end
                        [~,ampl] = readdicomfiles2d(pathname1);
                        
                        [hdr,phases,dicomhdr] = readdicomfiles2d(pathname2);
                        %                         disp([ii 'B0gre center frequency: ' num2str(hdr.sf)])
                        
                        if app.chkRegister.Value && strcmp(ii,'pre')
                            app.txtDisp.Value = 'Applying tranformations';
                            for jj=1:size(ampl,3)
                                ampl(:,:,jj) = imwarp(ampl(:,:,jj),app.pre.tform,'OutputView',imref2d(size(app.post.refimage)));
                                phases(:,:,jj) = imwarp(phases(:,:,jj),app.pre.tform,'OutputView',imref2d(size(app.post.refimage)));
                            end
                        end
                        
                        phases_scaled = pi * ( (phases-2048.0)/2048.0 );  % Scale phase to be between -pi to +pi
                        
                        app.txtDisp.Value = 'Calculating GRE B0 map';
                        B0map = NWcalcB0gre([],phases_scaled,hdr(1))/hdr(1).sf;
                        if 1 % Always filter B0map app.fit.isfilt
                            B0map = anisodiff(B0map,20,50,0.03,1);
                        end
                        roimask( abs(B0map) > app.numB0thresh.Value ) = 0;
                        if strcmp(ii,'pre')
                            app.pre.B0GREA = ampl;
                            app.pre.B0GREP = phases_scaled;
                            app.pre.B0GREhdr = hdr(1);
                            app.pre.B0map = B0map;
                            app.pre.roimask = roimask;
                        else
                            app.post.B0GREA = ampl;
                            app.post.B0GREP = phases_scaled;
                            app.post.B0GREhdr = hdr(1);
                            app.post.B0map = B0map;
                            app.post.roimask = roimask;
                        end
                    end
                    
                end
                
                %% B1
                for ii={'pre','post'}
                    app.txtDisp.Value = 'Loading B1 images';
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.B1pre);
                        roimask = app.pre.roimask;
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.B1post);
                        roimask = app.post.roimask;
                    end
                    [hdr,images,dicomhdr] = readdicomfiles2d(pathname1);
                    %                     disp([ii 'B1 center frequency: ' num2str(hdr.sf)])
                    
                    if app.chkRegister.Value && strcmp(ii,'pre')
                        app.txtDisp.Value = 'Applying tranformations';
                        for jj=1:size(images,3)
                            images(:,:,jj) = imwarp(images(:,:,jj),app.pre.tform,'OutputView',imref2d(size(app.post.refimage)));
                        end
                    end
                    
                    if ~isempty(strfind(hdr.SeqName,'prep_moco')) && isequal(size(images,3),3) % NW check this
                        alpha = hdr.WIPdbl(2)*pi/180;
                        app.txtDisp.Value = 'Calculating B1 map';
                        B1map = calc_B1map_new(images(:,:,1),images(:,:,2),images(:,:,3),roimask,alpha);
                    elseif ~isempty(strfind(hdr.swversion,'VD13A')) && isequal(size(images,3),2)
                        alpha = hdr.WIPdbl(2)*pi/180;
                        %                         alpha = 15.2*pi/180; % CLIPPED RF
                        app.txtDisp.Value = 'Calculating B1 map';
                        B1map = calc_B1map(images(:,:,1),images(:,:,2),roimask,alpha);
                    elseif ~isempty(strfind(lower(pathname1),'flip30')) && isequal(size(images,3),1)
                        alpha = pi/6.0;
                        pathparts = strsplit(pathname1,'_');
                        for jj=1:length(pathparts)
                            if strcmp(pathparts{jj},'flip30')
                                pathparts{jj} = 'flip60';
                            end
                        end
                        pathname2 = strjoin(pathparts,'_');
                        [~,image2] = readdicomfiles2d(pathname2);
                        app.txtDisp.Value = 'Calculating B1 map';
                        B1map = calc_B1map(images,image2,roimask,alpha);
                    end
                    if 1 % Always filter B1map app.fit.isfilt
                        B1map = anisodiff(B1map,20,50,0.03,1);
                    end
                    %                     B1map = ones(size(B1map)); % REMOVE THIS
                    roimask( B1map<app.numB1thresh.Value ) = 0;
                    roimask( B1map>1.3 ) = 0; % actual B1map should never be greater than 1.3
                    if strcmp(ii,'pre')
                        app.pre.B1map = B1map;
                        app.pre.B1images = images;
                        app.pre.alpha = alpha;
                        app.pre.roimask = roimask;
                        app.pre.B1hdr = hdr(1);
                    else
                        app.post.B1map = B1map;
                        app.post.B1images = images;
                        app.post.alpha = alpha;
                        app.post.roimask = roimask;
                        app.post.B1hdr = hdr(1);
                    end
                end
                
                %% CEST images
                for ii={'pre','post'}
                    multipaths = false;
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.CESTpre);
                        if isfield(app.subdirs,'CESTprepaths')
                            paths = app.subdirs.CESTprepaths;
                            multipaths = true;
                        end
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.CESTpost);
                        if isfield(app.subdirs,'CESTpostpaths')
                            paths = app.subdirs.CESTpostpaths;
                            multipaths = true;
                        end
                    end
                    app.txtDisp.Value = 'Loading CEST images';
                    if ~multipaths
                        [hdr,images,dicomhdr] = readdicomfiles2d(pathname1);
                    else
                        images = [];
                        for jj=1:length(paths)
                            [hdr,temp,dicomhdr] = readdicomfiles2d(paths{jj});
                            images = cat(3,images,temp);
                        end
                    end
                    %                     disp([ii 'CEST center frequency: ' num2str(hdr.sf)])
                    
                    if app.chkRegister.Value && strcmp(ii,'pre')
                        app.txtDisp.Value = 'Applying tranformations';
                        for jj=1:size(images,3)
                            images(:,:,jj) = imwarp(images(:,:,jj),app.pre.tform,'OutputView',imref2d(size(app.post.refimage)));
                        end
                    end
                    
                    nfreq = hdr.reps/2;
                    nfiles = hdr.reps;
                    seqname = hdr.SeqName;
                    swversion = hdr.swversion;
                    if ( strfind( swversion, 'VD13A' ) )
                        if (~strfind(seqname,'cest_flash'))
                            warning('not cest_flash sequence')
                            disp(seqname)
                        end
                        wipppmindex = 3;
                        prepmodeindex = 1;
                        cestz2value = 5;
                        cestzvalue = 1e5; % arbitrarily large
                        CESTpw = hdr.WIPlong(11);
                        CESTpw1 = hdr.WIPlong(13);
                        CESTb1 = hdr.WIPlong(14);
                        CESTdc = hdr.WIPdbl(1);
                        reqreps = hdr.WIPlong(8);
                        mocoreps = 1;
                    elseif ( strfind( swversion, 'VD13D' ) )                        
                        seqname = strsplit(seqname, '\');
                        seqname = seqname{end};
                        
                        if strcmp(seqname,'prep_moco')
                            wipppmindex = 2;
                            prepmodeindex = 2;
                            cestz2value = 3;
                            cestzvalue = 11; % NW
                            CESTpw = 0.001*hdr.WIPlong(13);
                            CESTpw1 = 0.001*hdr.WIPlong(14);
                            CESTb1 = hdr.WIPlong(16);
                            CESTdc = hdr.WIPdbl(1);
                            reqreps = hdr.WIPlong(9);
                            mocoreps = hdr.WIPlong(11);
                        elseif strcmp(seqname,'prep_moco_iB0')
                            wipppmindex = 3; % iB0
                            prepmodeindex = 2;
                            cestz2value = 3;
                            cestzvalue = 11; % NW
                            CESTpw = 0.001*hdr.WIPlong(15); % iB0
                            CESTpw1 = 0.001*hdr.WIPlong(16); % iB0
                            CESTb1 = hdr.WIPlong(18); % iB0
                            CESTdc = hdr.WIPdbl(1);
                            reqreps = hdr.WIPlong(10); % NW iB0
                            mocoreps = hdr.WIPlong(12); % NW iB0
                        else
                            wipppmindex = input(' Type in the value for wipppmindex : ');
                            prepmodeindex = input(' Type in the value for prepmodeindex : ');
                            cestzvalue = input(' Type in CESTz value : ');
                            CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                            CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                            CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                            CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                        end
                    else
                        if (strfind(seqname,'prep_moco'))
                            wipppmindex = 2;
                            prepmodeindex = 2;
                            cestz2value = 3;
                            if ( hdr.WIPlong(19) >= 170 )
                                CESTpw = hdr.WIPlong(10);
                                CESTb1 = hdr.WIPlong(13);
                                CESTpw1 = hdr.WIPlong(11);
                                CESTdc = hdr.WIPdbl(1);
                            else
                                CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                                CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                            end
                        elseif (strfind(seqname,'prep_cv'))
                            wipppmindex = 2;
                            prepmodeindex = 1;
                            cestz2value = 8;
                            if ( hdr.WIPlong(19) >= 170 )
                                CESTpw = hdr.WIPlong(10);
                                CESTb1 = hdr.WIPlong(13);
                                CESTpw1 = hdr.WIPlong(11);
                                CESTdc = hdr.WIPdbl(1);
                            else
                                CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                                CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                            end
                        elseif (strfind(seqname,'prep_flash'))
                            prepmodeindex = 1;
                            cestz2value = 8;
                            if ( hdr.WIPlong(19) >= 170 )
                                CESTpw = hdr.WIPlong(11);
                                CESTb1 = hdr.WIPlong(12);
                                CESTpw1 = hdr.WIPlong(15);
                                CESTdc = hdr.WIPdbl(1);
                                wipppmindex = 3;
                            else
                                CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                                CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                                wipppmindex = 5;
                            end
                        end
                    end
                    if ~exist('reqreps','var')
                        warning('Required reps and moco reps were not read in')
                        reqreps = 1;
                        mocoreps = 1;
                    end
                    posimages = images(:,:,1:2:end);
                    negimages = images(:,:,2:2:end);
                    ppmbegin = hdr.WIPdbl(wipppmindex+1);  % begin
                    ppmend   = hdr.WIPdbl(wipppmindex+2);  % end
                    ppmstep  = hdr.WIPdbl(wipppmindex+3);  % step
                    if (ppmbegin > ppmend)
                        ppmstep = -ppmstep;
                    end
                    CESTppmlist = ppmbegin:ppmstep:ppmend;
                    if hdr.WIPlong(prepmodeindex) == cestzvalue
                        DSimage = negimages(:,:,1);
                        posimages = posimages(:,:,2:2:end);
                        negimages = negimages(:,:,2:2:end);
                    end
                    if strcmp(ii,'pre')
                        app.pre.CESTpw = CESTpw;
                        app.pre.CESTb1 = CESTb1;
                        app.pre.CESTpw1 = CESTpw1;
                        app.pre.CESTdc = CESTdc;
                        app.pre.reqreps = reqreps;
                        app.pre.mocoreps = mocoreps;
                        app.pre.CESTppmlist = CESTppmlist;
                        app.pre.CESTposimages = posimages;
                        app.pre.CESTnegimages = negimages;
                        app.pre.CESThdr = hdr(1);
                    else
                        app.post.CESTpw = CESTpw;
                        app.post.CESTb1 = CESTb1;
                        app.post.CESTpw1 = CESTpw1;
                        app.post.CESTdc = CESTdc;
                        app.post.reqreps = reqreps;
                        app.post.mocoreps = mocoreps;
                        app.post.CESTppmlist = CESTppmlist;
                        app.post.CESTposimages = posimages;
                        app.post.CESTnegimages = negimages;
                        app.post.CESThdr = hdr(1);
                    end
                end
                
                
                %% Corrected CEST
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        roimask = app.pre.roimask;
                        posimages = app.pre.CESTposimages;
                        negimages = app.pre.CESTnegimages;
                        ppmlist = app.pre.CESTppmlist;
                        reqreps = app.pre.reqreps;
                        mocoreps = app.pre.mocoreps;
                        B1map = app.pre.B1map;
                        B0map = app.pre.B0map;
                        CESTb1 = app.pre.CESTb1;
                        refimage = app.pre.refimage;
                    else
                        roimask = app.post.roimask;
                        posimages = app.post.CESTposimages;
                        negimages = app.post.CESTnegimages;
                        ppmlist = app.post.CESTppmlist;
                        reqreps = app.post.reqreps;
                        mocoreps = app.post.mocoreps;
                        B1map = app.post.B1map;
                        B0map = app.post.B0map;
                        CESTb1 = app.post.CESTb1;
                        refimage = app.post.refimage;
                    end
                    
                    app.txtDisp.Value = 'Calculating raw CEST maps';
                    nCEST = length(ppmlist);
                    rawCESTmap = zeros(size(posimages,1),size(posimages,2),reqreps*mocoreps);
                    imageindex = find( abs(ppmlist - app.numCESTppm.Value) == min(abs(ppmlist - app.numCESTppm.Value)) );
                    for cc=1:reqreps*mocoreps
                        image1 = negimages(:,:,imageindex+(cc-1)*nCEST);
                        image2 = posimages(:,:,imageindex+(cc-1)*nCEST);
                        if app.radNeg.Value
                            normppm = -app.numCESTppm.Value;
                            normimage = image1;
                        elseif app.radMT.Value
                            [fname,pname] = uigetfile({[app.mainDir filesep '*.dcm'];[app.mainDir filesep '*.ima']},...
                                'Choose dicom for normalization');
                            [hdr,normimage] = readdicomfiles2d(fullfile(pname,fname));
                            normppm = inputdlg('Enter saturation offset in ppm ','MT offset');
                        else
                            normppm = NaN;
                            normimage = refimage;
                        end
                        image3 = 100*(image1-image2) ./ (normimage+eps) .* roimask;
                        if app.fit.isfilt
                            image3 = anisodiff(image3,20, 50, 0.03, 1);
                        end
                        rawCESTmap(:,:,cc) = image3;
                    end
                    if strcmp(ii,'pre')
                        app.pre.rawCESTmap = rawCESTmap;
                        app.pre.normimage = normimage;
                    else
                        app.post.rawCESTmap = rawCESTmap;
                        app.post.normimage = normimage;
                    end
                    
                    app.txtDisp.Value = 'Calculating B0 corrected CEST maps';
                    
                    b0corrmaps = zeros(size(rawCESTmap));
                    b0b1corrmaps = zeros(size(rawCESTmap));
                    for cc = 1:reqreps*mocoreps
                        pimg = posimages(:,:,1+(cc-1)*nCEST:cc*nCEST);
                        nimg = negimages(:,:,1+(cc-1)*nCEST:cc*nCEST);
                        if ( min(ppmlist) > 0.01 )
                            [pout,nout] = mexB0correctedCESTimglfit(app.numCESTppm.Value,ppmlist,pimg,nimg,B0map,double(roimask));
                        else
                            nppm = length(ppmlist);
                            [ppmsort,indexsort] = sort(ppmlist);
                            pimgin = pimg*0;
                            nimgin = nimg*0;
                            for aa = 1:nppm
                                pimgin(:,:,aa) = pimg(:,:,indexsort(aa));
                                nimgin(:,:,aa) = nimg(:,:,indexsort(aa));
                            end
                            [pout,nout] = mexB0corr_interp_map_zspecinteg(app.numCESTppm.Value,ppmsort,pimgin,nimgin,B0map,double(roimask));
                        end
                        if app.fit.isfilt
                            B0corrp = anisodiff(pout,20,50,0.03,1);
                            B0corrn = anisodiff(nout,20,50,0.03,1);
                        else
                            B0corrp = pout;
                            B0corrn = nout;
                        end
                        
                        B0corrmap = 100 * (B0corrn - B0corrp)./(B0corrn+eps); %normimage; % should this be B0corrn instead of normimage in denom
                        B0corrmap(~isfinite(B0corrmap)) = 0;
                        b0corrmaps(:,:,cc) = B0corrmap;
                        
                        
                        app.txtDisp.Value = 'Calculating B1 corrected CEST maps';
                        B1corrtype = 0;
                        switch B1corrtype
                            case 0 % inverse
                                B1corrmap = roimask .* (B0corrmap ./ (B1map+eps));
                                B1corrmap(~isfinite(B1corrmap)) = 0;
                            case 1 % calibration data
                                if (handles.CF > 250.0)
                                    B1calib = [17.54 -2.36]; % 7T
                                else
                                    B1calib = [ 8.29 -1.98]; % 3T
                                end
                                b1scale = CESTb1 * 0.612/42.5756;
                                B1CR = B1map .* roimask * b1scale; % Abolute B1rms
                                CESTmax = b1scale * (B1calib(2)*b1scale + B1calib(1));
                                B1corrmap = B0corrmap + ( CESTmax - (B1calib(2)*(B1CR.^2) + B1calib(1)*B1CR) );
                        end
                        if app.fit.isfilt
                            B1corrmap = anisodiff(B1corrmap,20,50,0.03,1);
                        end
                        b0b1corrmaps(:,:,cc) = B1corrmap;
                    end
                    if strcmp(ii,'pre')
                        app.pre.B0corrCEST = b0corrmaps;
                        app.pre.B0B1corrCEST = b0b1corrmaps;
                    else
                        app.post.B0corrCEST = b0corrmaps;
                        app.post.B0B1corrCEST = b0b1corrmaps;
                    end
                    
                    
                end
                app.pre.CESTmap = app.pre.B0B1corrCEST;
                app.post.CESTmap = app.post.B0B1corrCEST;
                app.txtDisp.Value = 'DONE';
                                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: chkWassr
        function chkWassrValueChanged(app, event)
            value = app.chkWassr.Value;
            setDisplays(app);
        end

        % Button pushed function: butB1map
        function butB1mapButtonPushed(app, event)
            try
                
                if isfield(app.pre,'B1map') && isfield(app.post,'B1map')
                    B1pre = app.pre.B1map;
                    B1post = app.post.B1map;
                    roipre = app.pre.roimask;
                    roipost = app.post.roimask;
                    
                    h = NWplayim([B1pre.*roipre B1post.*roipost],'parula',true,'B1maps');
                    app.handle.B1 = h;
                else
                    warndlg(' No transmit B1 maps found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'B1map','png','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fullfile(app.mainDir,fname),form,res,rend,'-noui')
                end
            end
        end

        % Button pushed function: butROI
        function butROIButtonPushed(app, event)
            try
                
                if ~isfield(app.pre,'refimageb') || ~isfield(app.post,'refimageb')
                    warndlg(' No reference image found. Please CALCULATE first');
                elseif ~isfield(app.pre,'rois') || ~isfield(app.post,'rois')
                    warndlg(' No ROIs found. Please Choose ROIs first')
                else
                    refpre = app.pre.refimageb .* app.pre.roimask;
                    refpost = app.post.refimageb .* app.post.roimask;
                    
                    h = figure('Name','References/ROIs','NumberTitle','off'); ax = axes(h);
                    refs = cat(2,refpre,refpost);
                    rois = cat(2,app.pre.rois,app.post.rois);
                    
                    NWimoverlay(cat(3,refs,rois),0.5,ax,app.fit.colors);
                    colormap('bone')
                    axis off
                    axis equal
                    
                    b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
                        'callback',@printax,'string','Print','fontweight','bold');
                    app.handle.ref = h;
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'ROIs','png','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fullfile(app.mainDir,fname),form,res,rend,'-noui')
                end
            end
        end

        % Button pushed function: butB0map
        function butB0mapButtonPushed(app, event)
            try
                
                if isfield(app.pre,'B0map') && isfield(app.post,'B0map')
                    B0pre = app.pre.B0map;
                    B0post = app.post.B0map;
                    roipre = app.pre.roimask;
                    roipost = app.post.roimask;
                    
                    h = NWplayim([B0pre.*roipre B0post.*roipost],'parula',true,'B0maps');
                    app.handle.B0 = h;
                else
                    warndlg(' No field maps found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'B0maps','png','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fullfile(app.mainDir,fname),form,res,rend,'-noui')
                end
            end
        end

        % Button pushed function: butCESTw_im
        function butCESTw_imButtonPushed(app, event)
            try
                
                if isfield(app.pre,'CESTposimages') && isfield(app.post,'CESTposimages')
                    neg = cat(3,app.pre.CESTnegimages,app.post.CESTnegimages);
                    sep = zeros(size(app.pre.CESTnegimages,1),2*size(app.pre.CESTnegimages,2));
                    pos = cat(3,app.pre.CESTposimages,app.post.CESTposimages);
                    im = cat(2,neg,pos);
                    im2 = im(:,:,1:size(app.pre.CESTposimages,3));
                    im2 = cat(3,im2,sep);
                    im2 = cat(3,im2,im(:,:,size(app.pre.CESTposimages,3)+1:end));
                    h = NWplayim(im2,'bone',false,'CESTweighted images');
                    app.handle.CESTw = h;
                else
                    warndlg(' No CEST images found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butCESTmap
        function butCESTmapButtonPushed(app, event)
            try                
                if isfield(app.pre,'CESTmap') && isfield(app.post,'CESTmap')
                    ncols = 5; % number of columns in montage
                    if isfield(app.fit,'plotmax')
                        h = NWmontim(app.pre.CESTmap,app.post.CESTmap,ncols,'jet',true,'CESTmaps',[0 0.8*app.fit.plotmax],app.mainDir);
                    else
                        h = NWmontim(app.pre.CESTmap,app.post.CESTmap,ncols,'jet',true,'CESTmaps',[0 16],app.mainDir);
                    end
                    app.handle.CESTmap = h;
                else
                    warndlg(' No CEST maps found. Please CALCULATE first');
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butSave
        function butSaveButtonPushed(app, event)
            try
                
                app.txtDisp.Value = 'Saving data...';
                clear out
                fname = [app.mainDir filesep 'CESTdata'];
                ii=0;
                savename = [fname date];
                while exist([savename '.mat'],'file')
                    ii=ii+1;
                    savename = [fname date '_' num2str(ii)];
                end
                app.handle.savename = savename;
                out.mainDir = app.mainDir;
                out.subdirs = app.subdirs;
                out.par.iswassr = app.chkWassr.Value;
                out.par.CESTppm = app.numCESTppm.Value;
                out.par.B0thresh = app.numB0thresh.Value;
                out.par.B1thresh = app.numB1thresh.Value;
                out.par.norm = getNormalization(app);
                out.pre = app.pre;
                out.post = app.post;
                out.fit = app.fit;
                save([savename '.mat'],'out');
                app.txtDisp.Value = 'DONE';
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butLoad
        function butLoadButtonPushed(app, event)
            try
                
                if exist('/media/cmroi/2A3E6A7F79133A73','dir')
                    [fname,path] = uigetfile('/media/cmroi/2A3E6A7F79133A73/*.mat','Choose a previously-saved .mat file'); % 140GB hard drive
                else
                    [fname,path] = uigetfile('*.mat','Choose a previously-saved .mat file');
                end
                if fname
                    app.txtDisp.Value = 'Loading data...';
                    disp(fullfile(path,fname))
                    in = load(fullfile(path,fname),'-mat');
                    app.mainDir = in.out.mainDir;
                    app.subdirs = in.out.subdirs;
                    app.pre = in.out.pre;
                    app.post = in.out.post;
                    app.fit = in.out.fit;
                    app.chkWassr.Value = in.out.par.iswassr;
                    app.txtMainDir.Value = app.mainDir;
                    app.numCESTppm.Value = in.out.par.CESTppm;
                    app.numB0thresh.Value = in.out.par.B0thresh;
                    app.numB1thresh.Value = in.out.par.B1thresh;
                    setNormalization(app,in.out.par.norm)
                    setDisplays(app)
                    app.txtDisp.Value = 'DONE';
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butChooseROI
        function butChooseROIButtonPushed(app, event)
            try
                
                refpre = app.pre.refimageb .* app.pre.roimask;
                refpost = app.post.refimageb .* app.post.roimask;
                
                %% Determine ROIs from post exercise image
                app.txtDisp.Value = 'Getting ROIs...';
                if isfield(app.post,'roipos') && isequal(length(app.post.roipos),app.numROIs.Value)
                    [postpos,postmasks] = NWcreateROITemplate(refpost,app.post.roipos,[],app.fit.roinames);
                else
                    [postpos,postmasks] = NWcreateROITemplate(refpost,app.numROIs.Value,[],app.fit.roinames);
                end
                
                if app.chkRegister.Value
                    % Get transformation between pre/post exercise
                    tform1 = imregcorr(refpre,refpost);
                    
                    [optimizer,metric] = imregconfig('monomodal');
                    tform = imregtform(refpost,refpre,'affine',optimizer,metric,'InitialTransformation',tform1); % uses affine transform between pre/post exercise
                    
                    % Apply transformation to ALL pre-exercise scans
                    
                    premasks = postmasks;
                    for ii=1:size(masks,3)
                        premasks(:,:,ii) = imwarp(postmasks,tform); % warp masks as well
                    end
                else                    
                    if isfield(app.pre,'roipos') && isequal(length(app.pre.roipos),app.numROIs.Value)
                        [prepos,premasks] = NWcreateROITemplate(refpre,app.pre.roipos,[],app.fit.roinames);
                    else
                        [prepos,premasks] = NWcreateROITemplate(refpre,postpos,[],app.fit.roinames);
                    end
                end
                
                sumroispre = squeeze(sum(sum(premasks,2),1));
                sumroispost = squeeze(sum(sum(postmasks,2),1));
                percentDiff = 100*abs(sumroispre-sumroispost)./sumroispost;
                if max(percentDiff(:))>20 % is 20% a reasonable amount?
                    hwarn = warndlg('Large Difference in ROI sizes pre and post exercise','Warning');
                    uiwait(hwarn)
                end
                app.pre.rois = premasks;
                app.post.rois = postmasks;
                app.fit.roipix.pre = sumroispre(:)'; % row vector
                app.fit.roipix.post = sumroispost(:)';
                app.pre.roipos = prepos;
                app.post.roipos = postpos;
                
                %% Fit recovery curves
                app.txtDisp.Value = 'Fitting recovery curves...';
                
                
                myfun = app.fit.myfun;
                par0 = app.fit.par0;
                
                
                if isfield(app.fit,'parnames')
                    parnames = app.fit.parnames;
                else
                    switch length(par0)
                        case 4
                            parnames = {'% Increase','Recovery constant','POST baseline','PRE baseline'};
                        case 5
                            parnames = {'% Increase','Recovery constant','POST baseline','PRE slope','PRE baseline'};
                        otherwise
                            parnames = [];
                    end
                end
                
                npars = length(par0);
                if isequal(npars,length(parnames))
                    parnames{npars+1} = 'Voxels Post';
                    parnames{npars+2} = 'Voxels Pre';
                end
                app.fit.parnames = parnames;
                
                
                cestpre = app.pre.CESTmap;
                cestpost = app.post.CESTmap;
                cest = cat(3,cestpre,cestpost);
                npre = size(cestpre,3);
                npost = size(cestpost,3);
                
                roipre = app.pre.rois;
                roipost = app.post.rois;
                nrois = size(roipre,3);
                %             sumroispre = sum(sum(roipre,2),1);
                %             sumroispost = sum(sum(roipost,2),1);
                
                if strfind(app.pre.refhdr.swversion,'VD13A')
                    scandurpre = 1e-3*app.pre.CESThdr.WIPlong(7)*2*length(app.pre.CESTppmlist); % 2 for +,- frequencies
                    scandurpost = 1e-3*app.post.CESThdr.WIPlong(7)*2*length(app.post.CESTppmlist);
                elseif strfind(app.pre.refhdr.swversion,'VD13D')
                    scandurpre = 1e-3*app.pre.CESThdr.WIPlong(8)*2*length(app.pre.CESTppmlist); % 2 for +,- frequencies
                    scandurpost = 1e-3*app.post.CESThdr.WIPlong(8)*2*length(app.post.CESTppmlist);
                else
                    error('unknown scan dur')
                end
                timepoints = [-scandurpre*(npre-0.5):scandurpre:0 scandurpost*((1:npost)-0.5)];
                
                %             button = questdlg('Select weighting for recovery fit','Weight','constant','1/N','SNR','SNR');
                button = questdlg4NW('Select weighting for recovery fit','Weight','1','1/N','S','S/N','S/N');
                
                means = ones(nrois,npre+npost); stds = ones(nrois,npre+npost);
                a = zeros(length(par0),nrois); r = zeros(npre+npost,nrois); J = zeros(npre+npost,length(par0),nrois);
                cov = zeros(length(par0),length(par0),nrois); mse = zeros(1,nrois);
                fitdata = zeros(length(timepoints),nrois);
                ci95 = zeros(length(par0),2,nrois); ci90 = ci95;
                warning('off','stats:nlinfit:IllConditionedJacobian');
                for ii=1:nrois
                    for jj=1:npre+npost
                        cestpoint = cest(:,:,jj);
                        if ii<npre+1
                            cestroi = cestpoint(roipre(:,:,ii)>0);
                        else
                            cestroi = cestpoint(roipost(:,:,ii)>0);
                        end
                        means(ii,jj) = mean(cestroi);
                        stds(ii,jj) = std(cestroi);
                    end
                    switch button
                        case '1'
                            weights = ones(size(means));
                        case '1/N'
                            weights = 1./stds;
                        case 'S'
                            weights = means;
                        case 'S/N'
                            weights = means./stds;
                    end
                    weights = abs(weights);
                    [a(:,ii),r(:,ii),J(:,:,ii),cov(:,:,ii),mse(:,ii)] = nlinfit(timepoints,means(ii,:),myfun,par0,'weights',weights(ii,:));
                    ci95(:,:,ii) = nlparci(a(:,ii),r(:,ii),'covar',cov(:,:,ii));
                    ci90(:,:,ii) = nlparci(a(:,ii),r(:,ii),'covar',cov(:,:,ii),'alpha',0.1);
                    fitdata(:,ii) = myfun(a(:,ii),timepoints);
                end
                warning('on','stats:nlinfit:IllConditionedJacobian');
                maxsig = means+stds;
                maxsig = max(maxsig(:));
                app.fit.nrois = nrois;
                app.fit.pars = a;
                app.fit.res = r;
                app.fit.J = J;
                app.fit.cov = cov;
                app.fit.mse = mse;
                app.fit.confidence_intervals95 = ci95;
                app.fit.confidence_intervals90 = ci90;
                app.fit.fitdata = fitdata;
                app.fit.means = means;
                app.fit.stds = stds;
                app.fit.timepoints = timepoints;
                app.fit.plotmax = maxsig;
                app.txtDisp.Value = 'DONE';
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: butROIrecover
        function butROIrecoverButtonPushed(app, event)
            try
                
                nrois = app.fit.nrois;
                ref = app.post.refimageb .* app.post.roimask;
                rois = app.post.rois;
                fitdata = app.fit.fitdata;
                means = app.fit.means;
                stds = app.fit.stds;
                colors = app.fit.colors;
                timepoints = app.fit.timepoints;
                
                
                h = figure('Name','ROIs ','NumberTitle','off');
                ax = axes(h); hold on
                for ii=1:nrois
                    %                 h = figure('Name',['ROI ' num2str(ii)],'NumberTitle','off');
                    %                 ax = axes(h); hold on
                    plot(ax,timepoints,fitdata(:,ii),'color',colors(ii,:),'linewidth',1.5)
                    if isfield(app.fit,'plotmax')
                        ylim([0 1.2*app.fit.plotmax])
                    else
                        ylim([0 25])
                    end
                    xlim([timepoints(1)-10 timepoints(end)+10])
                    errorbar(ax,timepoints,means(ii,:),stds(ii,:),'marker','o','color',colors(ii,:))
                    grid on
                    xlabel('Time (s)')
                    ylabel('% Asym')
                    %                 ax2 = axes(h,'Position',[0.65 0.60 0.25 0.25]);
                    %                 NWimoverlay(cat(3,ref,rois(:,:,ii)),0.5,ax2,colors(ii,:));
                    %                 colormap('bone')
                    %                 axis off
                    %                 axis equal
                end
                ax2 = axes(h,'Position',[0.67 0.65 0.25 0.25]);
                NWimoverlay(cat(3,ref,rois),0.5,ax2,colors);
                colormap('bone')
                axis off
                axis equal
                
                b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
                    'callback',@printax,'string','Print','fontweight','bold');
                
                e1 = uicontrol('Parent',h,'style','edit','units','normalized','position',[.21 .93 .05 .05],...
                    'callback',@ylimMin);
                
                e2 = uicontrol('Parent',h,'style','edit','units','normalized','position',[.34 .93 .05 .05],...
                    'callback',@ylimMax);
                
                te1 = uicontrol('Parent',h,'style','text','units','normalized','position',[.15 .93 .06 .05],...
                    'string','YMin','HorizontalAlignment','right','fontweight','bold');
                
                te2 = uicontrol('Parent',h,'style','text','units','normalized','position',[.28 .93 .06 .05],...
                    'string','YMax','HorizontalAlignment','right','fontweight','bold');
                
                updateStrings(ylim(ax))
                
                printROIstats(app)
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'recovery','png','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fullfile(app.mainDir,fname),form,res,rend,'-noui')
                end
            end
            
            function ylimMax(source,callbackdata)
                yl = ylim(ax);
                if str2double(get(e2,'string'))>yl(1)
                    yl(2) = str2double(get(e2,'string'));
                    ylim(ax,yl);
                else
                    updateStrings(yl)
                end
            end
            
            function ylimMin(source,callbackdata)
                yl = ylim(ax);
                if str2double(get(e1,'string'))<yl(2)
                    yl(1) = str2double(get(e1,'string'));
                    ylim(ax,yl);
                else
                    updateStrings(yl)
                end
            end
            
            function updateStrings(yl)
                set(e1,'string',num2str(yl(1),3))
                set(e2,'string',num2str(yl(2),3))
            end
        end

        % Button pushed function: butExport
        function butExportButtonPushed(app, event)
            try
                
                app.txtDisp.Value = 'Exporting data...';
                if isfield(app.handle,'savename') && ~exist([app.handle.savename '.csv'],'file')
                    savename = app.handle.savename;
                else
                    fname = [app.mainDir filesep 'CESTdata'];
                    savename = [fname date];
                    ii=0;
                    while exist([savename '.csv'],'file')
                        ii=ii+1;
                        savename = [fname date '_' num2str(ii)];
                    end
                end
                fid = fopen([savename '.csv'],'w');
                if ~isequal(fid,-1)
                    nrois = app.fit.nrois;
                    npars = length(app.fit.parnames);
                    parnames = [{'ROI'},app.fit.parnames];
                    fprintf(fid,[repmat('%s,',1,npars+1) '\n'],parnames{:});
                    str = {'time','mean','std','fit'};
                    lab = {'','ROI 1','',''};
                    if nrois>1
                        N = cat(1,1:nrois,app.fit.pars,app.fit.roipix.post,app.fit.roipix.pre);
                        Nlb95 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals95(:,1,:)));
                        Nub95 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals95(:,2,:)));
                        Nlb90 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals90(:,1,:)));
                        Nub90 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals90(:,2,:)));
                        for ii=2:nrois
                            str = [str {'mean','std','fit'}];
                            lab = [lab {['ROI ' num2str(ii)],'',''}];
                        end
                    end
                    fprintf(fid,[repmat('%3.2f,',1,npars+1) '\n'],N);
                    fprintf(fid,'\n Confidence Intervals');
                    fprintf(fid,'\n 95%%\n');
                    fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nlb95);
                    fprintf(fid,'\n');
                    fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nub95);
                    fprintf(fid,'\n 90%%\n');
                    fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nlb90);
                    fprintf(fid,'\n');
                    fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nub90);
                    fprintf(fid,'\n\n');
                    ncols = 3*nrois + 1;
                    fprintf(fid,[repmat('%s,',1,ncols) '\n'],lab{:});
                    fprintf(fid,[repmat('%s,',1,ncols) '\n'],str{:});
                    M = zeros(length(app.fit.timepoints),ncols);
                    M(:,1) = app.fit.timepoints(:);
                    M(:,2:3:end) = app.fit.means';
                    M(:,3:3:end) = app.fit.stds';
                    M(:,4:3:end) = app.fit.fitdata;
                    fprintf(fid,[repmat('%3.3f,',1,ncols) '\n'],M.');
                    fclose(fid);
                    app.handle.savename = savename;
                    app.txtDisp.Value = 'DONE';
                else
                    app.txtDisp.Value = 'Could not export csv...';
                end
                
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Close request function: figExerciseCEST
        function figExerciseCESTCloseRequest(app, event)
            rmpath('common');
            diary off
            delete(app);
        end

        % Button pushed function: butClear
        function butClearButtonPushed(app, event)
            app.pre = [];
            app.post = [];
            app.handle = [];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figExerciseCEST = uifigure;
            app.figExerciseCEST.Name = 'Exercise CEST';
            app.figExerciseCEST.CloseRequestFcn = createCallbackFcn(app, @figExerciseCESTCloseRequest, true);
            setAutoResize(app, app.figExerciseCEST, false)
            app.NormalizationGroup = uibuttongroup(app.figExerciseCEST);
            app.NormalizationGroup.Title = 'Normalization';
            app.radNeg = uiradiobutton(app.NormalizationGroup);
            app.radNeg.Text = 'M-';
            app.radNeg.Value = true;
            app.radM0 = uiradiobutton(app.NormalizationGroup);
            app.radM0.Text = 'M0';
            app.radMT = uiradiobutton(app.NormalizationGroup);
            app.radMT.Text = 'MT';
            app.LabelEditField = uilabel(app.figExerciseCEST);
            app.LabelEditField.Text = 'Main Directory';
            app.txtMainDir = uieditfield(app.figExerciseCEST, 'text');
            app.txtMainDir.ValueChangedFcn = createCallbackFcn(app, @txtMainDirValueChanged, true);
            app.txtMainDir.Editable = 'off';
            app.butChangeDir = uibutton(app.figExerciseCEST, 'push');
            app.butChangeDir.ButtonPushedFcn = createCallbackFcn(app, @ChangeDir, true);
            app.butChangeDir.Text = 'Change Dir';
            app.LabelNumericEditField = uilabel(app.figExerciseCEST);
            app.LabelNumericEditField.Text = 'CEST ppm';
            app.numCESTppm = uieditfield(app.figExerciseCEST, 'numeric');
            app.numCESTppm.ValueDisplayFormat = '%.1f';
            app.numCESTppm.Value = 1.8;
            app.B0TolLabel = uilabel(app.figExerciseCEST);
            app.B0TolLabel.Text = 'B0 Tol';
            app.numB0thresh = uieditfield(app.figExerciseCEST, 'numeric');
            app.numB0thresh.Limits = [0 5];
            app.numB0thresh.ValueDisplayFormat = '%.2f';
            app.numB0thresh.Value = 0.3;
            app.B1ThreshLabel = uilabel(app.figExerciseCEST);
            app.B1ThreshLabel.Text = 'B1 Thresh';
            app.numB1thresh = uieditfield(app.figExerciseCEST, 'numeric');
            app.numB1thresh.Limits = [0 1];
            app.numB1thresh.ValueDisplayFormat = '%.2f';
            app.numB1thresh.Value = 0.2;
            app.butChooseROI = uibutton(app.figExerciseCEST, 'push');
            app.butChooseROI.ButtonPushedFcn = createCallbackFcn(app, @butChooseROIButtonPushed, true);
            app.butChooseROI.Text = 'Choose ROIs';
            app.butCalculate = uibutton(app.figExerciseCEST, 'push');
            app.butCalculate.ButtonPushedFcn = createCallbackFcn(app, @butCalculateButtonPushed, true);
            app.butCalculate.Text = 'READ/CALC';
            app.DisplayPanel = uipanel(app.figExerciseCEST);
            app.DisplayPanel.Title = 'Display';
            app.butB0map = uibutton(app.DisplayPanel, 'push');
            app.butB0map.ButtonPushedFcn = createCallbackFcn(app, @butB0mapButtonPushed, true);
            app.butB0map.Text = 'B0map';
            app.butCESTw_im = uibutton(app.DisplayPanel, 'push');
            app.butCESTw_im.ButtonPushedFcn = createCallbackFcn(app, @butCESTw_imButtonPushed, true);
            app.butCESTw_im.Text = 'CESTw images';
            app.butB1map = uibutton(app.DisplayPanel, 'push');
            app.butB1map.ButtonPushedFcn = createCallbackFcn(app, @butB1mapButtonPushed, true);
            app.butB1map.Text = 'B1map';
            app.butROI = uibutton(app.DisplayPanel, 'push');
            app.butROI.ButtonPushedFcn = createCallbackFcn(app, @butROIButtonPushed, true);
            app.butROI.Text = 'Ref/ROIs';
            app.butROIrecover = uibutton(app.DisplayPanel, 'push');
            app.butROIrecover.ButtonPushedFcn = createCallbackFcn(app, @butROIrecoverButtonPushed, true);
            app.butROIrecover.Text = 'ROI recovery';
            app.butCESTmap = uibutton(app.DisplayPanel, 'push');
            app.butCESTmap.ButtonPushedFcn = createCallbackFcn(app, @butCESTmapButtonPushed, true);
            app.butCESTmap.Text = 'CESTmaps';
            app.preExPanel = uipanel(app.figExerciseCEST);
            app.preExPanel.Title = 'Directories/Dicoms - PRE Exercise';
            app.Label2 = uilabel(app.preExPanel);
            app.Label2.Text = 'B1';
            app.txtB1Pre = uieditfield(app.preExPanel, 'text');
            app.Label3 = uilabel(app.preExPanel);
            app.Label3.Text = 'CEST';
            app.txtCESTPre = uieditfield(app.preExPanel, 'text');
            app.Label8 = uilabel(app.preExPanel);
            app.Label8.Text = 'B0';
            app.txtB0Pre = uieditfield(app.preExPanel, 'text');
            app.Label9 = uilabel(app.preExPanel);
            app.Label9.Text = 'Reference';
            app.txtRefPre = uieditfield(app.preExPanel, 'text');
            app.postExPanel = uipanel(app.figExerciseCEST);
            app.postExPanel.Title = 'Directories/Dicoms - POST Exercise';
            app.Label4 = uilabel(app.postExPanel);
            app.Label4.Text = 'Reference';
            app.txtRefPost = uieditfield(app.postExPanel, 'text');
            app.Label5 = uilabel(app.postExPanel);
            app.Label5.Text = 'B0';
            app.txtB0Post = uieditfield(app.postExPanel, 'text');
            app.Label6 = uilabel(app.postExPanel);
            app.Label6.Text = 'B1';
            app.txtB1Post = uieditfield(app.postExPanel, 'text');
            app.Label7 = uilabel(app.postExPanel);
            app.Label7.Text = 'CEST';
            app.txtCESTPost = uieditfield(app.postExPanel, 'text');
            app.LabelDropDown = uilabel(app.figExerciseCEST);
            app.LabelDropDown.Text = 'Change Dir';
            app.ChangeDirDropDown = uidropdown(app.figExerciseCEST);
            app.ChangeDirDropDown.Items = {'Ref Pre', 'B0 Pre', 'B1 Pre', 'CEST Pre', 'Ref Post', 'B0 Post', 'B1 Post', 'CEST Post', ''};
            app.ChangeDirDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDirDropDownValueChanged, true);
            app.ChangeDirDropDown.Value = '';
            app.chkWassr = uicheckbox(app.figExerciseCEST);
            app.chkWassr.ValueChangedFcn = createCallbackFcn(app, @chkWassrValueChanged, true);
            app.chkWassr.Text = 'WASSR';
            app.LabelEditField2 = uilabel(app.figExerciseCEST);
            app.LabelEditField2.Text = '';
            app.txtDisp = uieditfield(app.figExerciseCEST, 'text');
            app.txtDisp.Editable = 'off';
            app.butSave = uibutton(app.figExerciseCEST, 'push');
            app.butSave.ButtonPushedFcn = createCallbackFcn(app, @butSaveButtonPushed, true);
            app.butSave.Text = 'Save';
            app.butLoad = uibutton(app.figExerciseCEST, 'push');
            app.butLoad.ButtonPushedFcn = createCallbackFcn(app, @butLoadButtonPushed, true);
            app.butLoad.Text = 'Load';
            app.LabelNumericEditField3 = uilabel(app.figExerciseCEST);
            app.LabelNumericEditField3.Text = 'No.';
            app.numROIs = uieditfield(app.figExerciseCEST, 'numeric');
            app.numROIs.Limits = [0 9];
            app.numROIs.ValueDisplayFormat = '%.0f';
            app.numROIs.Value = 4;
            app.chkFilter = uicheckbox(app.figExerciseCEST);
            app.chkFilter.Text = 'Filter';
            app.chkFilter.Value = true;
            app.butExport = uibutton(app.figExerciseCEST, 'push');
            app.butExport.ButtonPushedFcn = createCallbackFcn(app, @butExportButtonPushed, true);
            app.butExport.Text = 'Export';
            app.butClear = uibutton(app.figExerciseCEST, 'push');
            app.butClear.ButtonPushedFcn = createCallbackFcn(app, @butClearButtonPushed, true);
            app.butClear.Text = 'Clear';
            app.chkRegister = uicheckbox(app.figExerciseCEST);
            app.chkRegister.Enable = 'off';
            app.chkRegister.Text = 'Register';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExerciseCEST()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figExerciseCEST)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figExerciseCEST)
        end
    end
end